classdef RobotController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        VelocityModeButton     matlab.ui.control.Button
        PositionModeButton     matlab.ui.control.Button
        GotoPointSmoothButton  matlab.ui.control.Button
        PickwithAngleButton    matlab.ui.control.Button
        PickandPutButton       matlab.ui.control.Button
        PutButton              matlab.ui.control.Button
        PickButton             matlab.ui.control.Button
        Z_PutEditField         matlab.ui.control.NumericEditField
        Z_PutEditFieldLabel    matlab.ui.control.Label
        Y_PutEditField         matlab.ui.control.NumericEditField
        Y_PutEditFieldLabel    matlab.ui.control.Label
        X_PutEditField         matlab.ui.control.NumericEditField
        X_PutEditFieldLabel    matlab.ui.control.Label
        Z_PickEditField        matlab.ui.control.NumericEditField
        Z_PickEditFieldLabel   matlab.ui.control.Label
        Y_PickEditField        matlab.ui.control.NumericEditField
        Y_PickEditFieldLabel   matlab.ui.control.Label
        X_PickEditField        matlab.ui.control.NumericEditField
        X_PickEditFieldLabel   matlab.ui.control.Label
        GotowithAngleButton    matlab.ui.control.Button
        AngleWEditField        matlab.ui.control.NumericEditField
        AngleWEditFieldLabel   matlab.ui.control.Label
        GripperReleaseButton   matlab.ui.control.Button
        GripperHoldButton      matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        PointZEditField        matlab.ui.control.NumericEditField
        PointZEditFieldLabel   matlab.ui.control.Label
        Y_GridEditField        matlab.ui.control.NumericEditField
        Y_GridEditFieldLabel   matlab.ui.control.Label
        X_GridEditField        matlab.ui.control.NumericEditField
        X_GridEditFieldLabel   matlab.ui.control.Label
        GotoPointButton        matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
        ConnectButton          matlab.ui.control.Button
        EditField5             matlab.ui.control.NumericEditField
        EditField5Label        matlab.ui.control.Label
        Slider5                matlab.ui.control.Slider
        Slider5Label           matlab.ui.control.Label
        EditField4             matlab.ui.control.NumericEditField
        EditField4Label        matlab.ui.control.Label
        Slider4                matlab.ui.control.Slider
        Slider4Label           matlab.ui.control.Label
        EditField3             matlab.ui.control.NumericEditField
        EditField3Label        matlab.ui.control.Label
        Slider3                matlab.ui.control.Slider
        Slider3Label           matlab.ui.control.Label
        EditField2             matlab.ui.control.NumericEditField
        EditField2Label        matlab.ui.control.Label
        Slider2                matlab.ui.control.Slider
        Slider2Label           matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        EditFieldLabel         matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lib_name
        port_num
        PROTOCOL_VERSION = 2.0
        BAUDRATE = 1000000
        DEVICENAME = "COM19"
    
        % IDs
        DXL_ID1 = 11
        DXL_ID2 = 12
        DXL_ID3 = 13
        DXL_ID4 = 14
        DXL_GRIP = 15
        DXL_IDS
    
        % addresses
        ADDR_MAX_POS = 48
        ADDR_MIN_POS = 52
        ADDR_PRO_TORQUE_ENABLE = 64
        ADDR_PRO_GOAL_POSITION = 116
        ADDR_PRO_PRESENT_POSITION = 132
        ADDR_PRO_OPERATING_MODE = 11
        ADDR_PRO_PROFILE_VELOCITY = 112
        ADDR_PRO_GOAL_VELOCITY = 104
    
        % constants
        TORQUE_ENABLE = 1
        TORQUE_DISABLE = 0
        MAX_POS = 3400
        MIN_POS = 600
        VELOCITY = 1000
    
        isConnected = false
        updateTimer
        
        thetaOffset = [0, pi/2-17.5*pi/180, -pi/2+17.5*pi/180, 0];
        thetaSign = [1 -1 -1 -1 1];  % 每个关节的方向（第5个是夹爪，先不反）
        
        GRIP_HOLD_POS = 35;
        GRIP_RELEASE_POS = -60;

        draw_enable = false;

        hArmLine;
        hGripperTrace;
        gripperTraceData;
        hRealTrace;
        realTraceData;

        vmax_deg = 60;
        alpha_plan_deg = 180;
        alpha_lim_deg = 180;

        sync_omega_max = [0,0,0,0]
        sync_alpha_max = [0,0,0,0]
        vel_last = [0,0,0,0]
        theta_tolerance = 0.5
        vel_tolerance = 0.5
    end

  
    methods (Access = private)
            function redraw(app)
                % 读角度（度->弧度）
                th_deg = [app.EditField.Value, ...
                          app.EditField2.Value, ...
                          app.EditField3.Value, ...
                          app.EditField4.Value,];
                th = deg2rad(th_deg);
            
                % FK 得到点
                P = app.fk_points(th);  % 3x5
                %disp("Wrist Position: ")
                %disp(P(:,4))
                gripper = P(:,5).';   % 1x3

                % 记录轨迹
                app.gripperTraceData = [app.gripperTraceData; gripper];

                % 画图
                %cla(app.UIAxes);
                set(app.hArmLine, 'XData', P(1,:), 'YData', P(2,:), 'ZData', P(3,:));

                % 更新红色轨迹
                if(app.draw_enable)
                    set(app.hGripperTrace, ...
                        'XData', app.gripperTraceData(:,1), ...
                        'YData', app.gripperTraceData(:,2), ...
                        'ZData', app.gripperTraceData(:,3));
                end

            end


            % ===== 通用回调：Slider拖动时 =====
            function ThetaSliderChanging(app, event, idx)
                val = event.Value;
                app.setTheta(idx, val, true);  % true=来自滑动条
            end
        
            % ===== 通用回调：Slider松手/或EditField改值 =====
            function ThetaControlChanged(app, idx, val)

                app.setTheta(idx, val, false);
            end

            function setTheta(app, idx, val, ~)
                % 1) 限幅（度）
                if(idx == 4)
                    val = max(-119, min(90, val));
                else
                    val = max(-90, min(90, val));
                end
            
                % 2) 同步 slider <-> editfield
                switch idx
                    case 1
                        app.Slider.Value  = val; app.EditField.Value  = val;
                    case 2
                        app.Slider2.Value = val; app.EditField2.Value = val;
                    case 3
                        app.Slider3.Value = val; app.EditField3.Value = val;
                    case 4
                        app.Slider4.Value = val; app.EditField4.Value = val;
                    case 5
                        app.Slider5.Value = val; app.EditField5.Value = val;
                end
            
                % 3) 先更新仿真（无论是否连接都能看）
                app.redraw();
            
                % 4) 再驱动实物（只有已连接才写）
                if ~app.isConnected
                    return;
                end
            
                % 5) 拖动时太频繁的话，可以只在松手/输入框时写
                %    如果你希望拖动实时带着动，就把这一段注释掉即可
                % if fromSlider
                %     return;
                % end
            
                % 6) idx -> 真正的电机ID（推荐用数组映射，别用 idx+10 猜）
                id = app.DXL_IDS(idx);   % [11 12 13 14 15]
                val_corr = app.thetaSign(idx) * val;

                % 7) 度 -> position
                goal_pos = app.deg2pos(val_corr);

                % 8) 写入电机（加 try/catch 防止异常卡UI）
                try
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, goal_pos);
                catch ME
                    % 写失败就断开标记，避免后续一直卡
                    disp(ME.message);
                    app.isConnected = false;
                end
            end

            function setTheta_sim(app, idx, val, ~)
                % 1) 限幅（度）
                if(idx == 4)
                    val = max(-119, min(90, val));
                else
                    val = max(-90, min(90, val));
                end
            
                % 2) 同步 slider <-> editfield
                switch idx
                    case 1
                        app.Slider.Value  = val; app.EditField.Value  = val;
                    case 2
                        app.Slider2.Value = val; app.EditField2.Value = val;
                    case 3
                        app.Slider3.Value = val; app.EditField3.Value = val;
                    case 4
                        app.Slider4.Value = val; app.EditField4.Value = val;
                    case 5
                        app.Slider5.Value = val; app.EditField5.Value = val;
                end
            
            end


            function set_omega(app, theta_f)
                start_pos = [0,0,0,0];
                ids = app.DXL_IDS(1:4);
                distance = [0,0,0,0];
                for k = 1:numel(ids)
                    start_pos(k) = pos2deg(app, read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION));
                    distance(k) = theta_f(k)-start_pos(k);
                end
                  
                absolute_distance = abs(distance);

                current_pos = start_pos;
                max_omega = app.vmax_deg;
                max_alpha = app.alpha_plan_deg;
                acc_distance = max_omega^2/(2*max_alpha);

                Dmax = max(absolute_distance);
                if Dmax <= app.theta_tolerance
                    for k = 1:numel(ids)
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_VELOCITY, 0);
                    end
                    app.vel_last = zeros(1,4);
                    return;

                elseif Dmax >= 2*acc_distance
                    accel_time = max_omega / max_alpha;
                    uniform_time = (Dmax -2*acc_distance)/max_omega;
                else
                    accel_time = sqrt(Dmax / max_alpha);
                    uniform_time = 0;
                end

                for k = 1:numel(ids)

                    %direction = sign(distance(k));

                    if uniform_time > 0
                        max_omega_i = absolute_distance(k)/(accel_time+uniform_time);
                        max_alpha_i = max_omega_i/accel_time;
                    else
                        max_omega_i = absolute_distance(k) /accel_time;
                        max_alpha_i = max_omega_i /accel_time;
                    end

                    max_omega_i = min(max_omega_i, app.vmax_deg);
                    max_alpha_i = min(max_alpha_i, app.alpha_plan_deg);

                    app.sync_omega_max(k) = max_omega_i;
                    app.sync_alpha_max(k) = max_alpha_i;
                end

                vel = zeros(1,4);
                vel_units = zeros(1,4,'int32');
                t0 = tic;

                while any(abs(theta_f - current_pos) > app.theta_tolerance)
                    for k = 1:numel(ids)
                        current_pos(k) = pos2deg(app, read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION));
                    end

                    dt = toc(t0);
                    dt = min(max(dt, 0.001), 0.05); 

                    for k = 1:numel(ids)
                        [v_cmd, ~] = trapezoidVelLimited(app, current_pos(k), start_pos(k), theta_f(k), app.sync_omega_max(k), app.sync_alpha_max(k), app.alpha_lim_deg, dt, app.vel_last(k));
                        vel(k) = v_cmd;
                        vel_units(k) = degps2velTick(app, v_cmd);
                        %write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PROFILE_VELOCITY, degps2velTick(app, vel(k)));
                    end
                    syncWriteProfileVelocity(app, ids, vel_units)
                    t0 = tic;

                    app.vel_last = vel;

                    pos_ok = all(abs(theta_f - current_pos) <= app.theta_tolerance);
                    vel_ok = all(abs(vel) <= app.vel_tolerance);   % 比如 0.5 deg/s 或更小
                    if pos_ok && vel_ok
                        break;
                    end
                    pause(0.02)
                end

                for k = 1:numel(ids)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PROFILE_VELOCITY, 0);
                end
                app.vel_last = zeros(1,4);
                disp("Smooth Movement Complete");
            end


            function [v_cmd, v_des] = trapezoidVelLimited(app, theta, theta0, thetaf, vmax, alpha_plan, alpha_lim, dt, v_last)

                if dt <= 0 || vmax <= 0 || alpha_plan <= 0 || alpha_lim <= 0
                    v_des = 0;
                    v_cmd = 0;
                    return;
                end
            
                % -------- 1) 先算理想速度 v_des（位置域梯形/三角）--------
                v_des = trapezoidVel(app, theta, theta0, thetaf, vmax, alpha_plan);
            
                % 数值安全：避免 NaN / Inf
                if ~isfinite(v_des)
                    v_des = 0;
                end
            
                % 速度限幅（绝对最大速度）
                v_des = clamp(app, v_des, -vmax, vmax);
            
                % -------- 2) 加速度限幅（每周期速度变化限幅）--------
                dv_max = alpha_lim * dt;
                dv = v_des - v_last;
                dv = clamp(app, dv, -dv_max, dv_max);
            
                v_cmd = v_last + dv;
            
                % 最终再限一次速度（双保险）
                v_cmd = clamp(app, v_cmd, -vmax, vmax);
            end
            
            % ====== 内部小工具：限幅 ======
            function y = clamp(~, x, lo, hi)
                y = min(max(x, lo), hi);
            end


            function v = trapezoidVel(~, theta, theta0, thetaf, vmax, alpha)
                if alpha <= 0 || vmax <= 0
                    v = 0; return;
                end
            
                dir = sign(thetaf - theta0);
                if dir == 0
                    v = 0; return;
                end
            
                sf = dir * (thetaf - theta0);       % 总距离(正)
                s  = dir * (theta  - theta0);       % 已走距离(正)
                s  = min(max(s, 0), sf);            % 夹紧，抗噪
            
                s_rem = sf - s;                     % 剩余距离
                v_brake = sqrt(2 * alpha * s_rem);  % 为了能刹停允许的最大速度
            
                v_mag = min(vmax, v_brake);         % 目标速度幅值
                v = dir * v_mag;
            end


            function T = dh_T(~, alpha, a, d, theta)
                % Standard DH (Craig):
                % T = RotZ(theta) * TransZ(d) * TransX(a) * RotX(alpha)
            
                ca = cos(alpha); sa = sin(alpha);
                ct = cos(theta); st = sin(theta);
            
                T = [ ct   -st*ca   st*sa   a*ct;
                      st    ct*ca  -ct*sa   a*st;
                      0       sa      ca      d;
                      0        0       0      1];
            end
            function Tall = fk_T_all(app, theta)
                % theta: 1x4 (rad) = [t1 t2 t3 t4]
                % returns Tall: 4x4x5, Tall(:,:,1)=I, Tall(:,:,k+1)=T0k
            
                % ---- Your DH table ----
                alpha = [deg2rad(90), 0, 0, 0];   % alpha0..alpha3
                a     = [0, 0.130, 0.124, 0.126]; % a0..a3
                d     = [0.077, 0, 0, 0];         % d1..d4
            
                % ---- Joint angle offsets (rad) ----
                % 让第3关节零位时默认向下 90°：   % 若方向反了，把 -pi/2 改成 +pi/2
            
                % ensure theta is 1x4
                theta = reshape(theta, 1, []);
                if numel(theta) ~= 4
                    error("fk_T_all: theta must be 1x4 (rad).");
                end
            
                theta_eff = theta + app.thetaOffset;
            
                Tall = zeros(4,4,5);
                Tall(:,:,1) = eye(4);
            
                T = eye(4);
                for i = 1:4
                    Ti = app.dh_T(alpha(i), a(i), d(i), theta_eff(i));
                    T = T * Ti;
                    Tall(:,:,i+1) = T;
                end
            end

            
            function P = fk_points(app, theta)
                % Returns joint positions for plotting links
                % P: 3x5 (base + 4 joints/end-effector)
                Tall = app.fk_T_all(theta);
            
                P = zeros(3,5);
                for k = 1:5
                    P(:,k) = Tall(1:3,4,k);
                end
            end


            function name = getLibName(~)
                if strcmp(computer,'PCWIN')
                    name = 'dxl_x86_c';
                elseif strcmp(computer,'PCWIN64')
                    name = 'dxl_x64_c';
                elseif strcmp(computer,'GLNX86')
                    name = 'libdxl_x86_c';
                elseif strcmp(computer,'GLNXA64')
                    name = 'libdxl_x64_c';
                elseif strcmp(computer,'MACI64')
                    name = 'libdxl_mac_c';
                else
                    error("Unsupported platform: %s", computer);
                end
            end

            function connectDxl(app)
                if app.isConnected
                    return;
                end
            
                app.lib_name = app.getLibName();
            
                % Load Libraries
                if ~libisloaded(app.lib_name)
                    loadlibrary(app.lib_name, 'dynamixel_sdk.h', ...
                        'addheader','port_handler.h', ...
                        'addheader','packet_handler.h');
                end
            
                % port/packet init
                app.port_num = portHandler(char(app.DEVICENAME));
                packetHandler();
            
                % Open port
                if ~openPort(app.port_num)
                    error("Failed to open port %s", app.DEVICENAME);
                end
            
                % Set baudrate
                if ~setBaudRate(app.port_num, app.BAUDRATE)
                    error("Failed to set baudrate");
                end
            
                % Disable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                end
            
                % Position control mode
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 3);
                end
            
                % Enable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_ENABLE);
                end

                % Set Speed
                for id = app.DXL_IDS
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_PROFILE_VELOCITY, app.VELOCITY);
                end
            
                % Limits + center
                for id = app.DXL_IDS
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MAX_POS, app.MAX_POS);
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MIN_POS, app.MIN_POS);
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, 2048);
                end
                pause(0.5);
            
                app.isConnected = true;
            end

            function disconnectDxl(app)
                if ~app.isConnected
                    return;
                end
            
                % stop timer first
                if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                    stop(app.updateTimer);
                end
            
                % Disable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                end
            
                % Close port
                closePort(app.port_num);
            
                % Unload library
                if libisloaded(app.lib_name)
                    unloadlibrary(app.lib_name);
                end
            
                app.isConnected = false;
            end
           
            function onTimerTick(app)
                if ~app.isConnected
                    return;
                end
            
                % 1) 从 UI 读目标角度（度） -> 转成 dynamixel position
                th_deg = [app.EditField.Value, ...
                          app.EditField2.Value, ...
                          app.EditField3.Value, ...
                          app.EditField4.Value, ...
                          app.EditField5.Value];   % 夹爪可用开合角
            
                goal = app.deg2pos(th_deg);
            
                % 2) 写入五个电机
                ids = app.DXL_IDS;
                for k = 1:numel(ids)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_POSITION, goal(k));
                end
            
                % 3) 读回位置（用于显示/3D）
                pos = zeros(1,numel(ids));
                for k = 1:numel(ids)
                    pos(k) = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION);
                end
            
                % 4) 用前4个关节做 FK（你给的 DH 表是4轴）
                theta_rad = app.pos2rad(pos(1:4));
                P = app.fk_points(theta_rad);   % 用我前面给你的 DH FK 函数
            
                cla(app.UIAxes);
                plot3(app.UIAxes, P(1,:),P(2,:),P(3,:), '-o','LineWidth',2);
                grid(app.UIAxes,'on'); axis(app.UIAxes,'equal'); view(app.UIAxes,3);
                drawnow limitrate;
            end
            
            function th = pos2rad(~, pos)
                th = (double(pos) - 2048) / 4096 * 2*pi;
            end
            
            function pos = rad2pos(~, th)
                pos = round(2048 + th/(2*pi)*4096);
            end
            
            function pos = deg2pos(app, th_deg)
                th = deg2rad(th_deg);
                pos = app.rad2pos(th);
            
                % 安全限幅（可选）
                pos = max(app.MIN_POS, min(app.MAX_POS, pos));
            end

            function deg = pos2deg(app, pos)
                th = app.pos2rad(app, pos);
                deg = app.rad2deg(th);
            end

            function pts = interp(~, p1,p2,N)
                t = linspace(0,1,N)';
                pts = p1.*(1-t) + p2.*t;
            end

            function drawSquare(app)
                traj = app.calcSquarePts();
            
            
                % ===== 初值：用当前UI角度（用于连续性/平滑）=====
                %last = deg2rad([app.EditField.Value, app.EditField2.Value, app.EditField3.Value, app.EditField4.Value]);
                
                for k = 1:size(traj,1)
                    %disp("Traj")
                    %disp(traj(k,:))
                    xd = traj(k,1);
                    yd = traj(k,2);
                    zd = traj(k,3);

                    GotoPoint(app, xd, yd, zd);

                    pause(0.03);

                    if app.isConnected
                        p_real = app.readRealWristXYZ();          % 1x3
                        app.realTraceData = [app.realTraceData; p_real];
                    
                        set(app.hRealTrace, ...
                            'XData', app.realTraceData(:,1), ...
                            'YData', app.realTraceData(:,2), ...
                            'ZData', app.realTraceData(:,3));
                    end

                end
            end

            function squarePts = calcSquarePts(app)
                % ====== 你在这里改参数 ======
                center = [grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value)];   % 方形中心 (x,y) 单位 m
                side   = 0.1;           % 边长 (m) 例如 8cm
                z      = app.PointZEditField.Value;           % 高度 (m)
                n      = 40;             % 每条边点数：20
            
                cx = center(1); cy = center(2);
                h  = side/2;
            
                % 四个角（逆时针）
                p1 = [cx-h, cy-h, z];
                p2 = [cx+h, cy-h, z];
                p3 = [cx+h, cy+h, z];
                p4 = [cx-h, cy+h, z];
            
                % 每条边 n 个点
                e1 = linspace3(app, p1, p2, n);
                e2 = linspace3(app, p2, p3, n);
                e3 = linspace3(app, p3, p4, n);
                e4 = linspace3(app, p4, p1, n);
            
                % 拼接，去掉重复角点（每段第一点=上一段最后一点）
                squarePts = [e1;
                             e2(2:end,:);
                             e3(2:end,:);
                             e4(2:end,:)];
            end
            
            function P = linspace3(~, p0, p1, n)
                t = linspace(0,1,n).';
                P = p0 + (p1 - p0).*t;
            end

            function GotoPoint(app, pos_x, pos_y, pos_z)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = 0.077;
                L2 = 0.130;
                L3 = 0.124;
                L4 = 0.126;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                % ===== phi candidate list (tool pitch in r-z plane) =====
                % prefer -90deg, then scan a range
                phi0 = -pi/2;
            
                % 根据你第4关节限位大概给一个可用范围（你可调）
                % 例如允许从 -120° 到 -30° （越往后越“抬起来”）
                phi_scan = linspace(deg2rad(-120), deg2rad(-30), 60);
            
                % 让 -90° 一定排第一，并去重
                phi_list = unique([phi0, phi_scan], 'stable');
            
                % ===== best solution record =====
                bestCost = inf;
                bestTh   = [];
                bestPhi  = NaN;
            
                % ===== sweep all phi =====
                foundVertical = false;
                for phi = phi_list
                    if(foundVertical)
                        break;
                    end
            
                    % --- tool tip -> wrist point (your current method, kept) ---
                    wx = px - L4*cos(phi)*cos(theta1);
                    wy = py - L4*cos(phi)*sin(theta1);
                    wz = pz - L4*sin(phi);
            
                    r = hypot(wx, wy);
                    z = wz - L1;
            
                    % --- 2R reachability ---
                    D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);
            
                    if abs(D) > 1
                        continue; % this phi makes wrist unreachable
                    end
            
                    % numerical clamp (still good near boundary)
                    D = max(min(D,1),-1);
            
                    % --- try both elbow branches ---
                    for sgn = [1, -1]
                        theta3 = atan2(sgn*sqrt(1-D^2), D);
                        theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                        theta4 = phi - theta2 - theta3;
            
                        % ===== joint limits check (same as your setTheta clamp) =====
                        % Convert to degrees in UI convention (before offset)
                        th = [theta1 theta2 theta3 theta4];
                        th_deg = rad2deg(th - app.thetaOffset);
            
                        % idx1..3: [-90,90], idx4: [-119,90]
                        if th_deg(1) < -90 || th_deg(1) > 90,   continue; end
                        if th_deg(2) < -90 || th_deg(2) > 90,   continue; end
                        if th_deg(3) < -90 || th_deg(3) > 90,   continue; end
                        if th_deg(4) < -119 || th_deg(4) > 90,  continue; end
            
                        % ===== cost function: prefer phi near -90°, and avoid huge wrist angle =====
                        % 你可以调权重：wPhi 越大越“死命保持竖直”
                        wPhi = 100;
                        wWrist = 1;
                        cost = wPhi*abs(phi - phi0) + wWrist*abs(theta4);
            
                        if cost < bestCost
                            bestCost = cost;
                            bestTh   = th;
                            bestPhi  = phi;
                            if(phi == -pi/2)
                                foundVertical = true;
                                disp("Found Vertical");
                                break; 
                            end
                        end
                    end
                end
            
                % ===== apply best solution =====
                if isempty(bestTh)
                    disp("IK failed: no feasible solution in phi range + joint limits.");
                    return;
                end
            
                th_deg = rad2deg(bestTh - app.thetaOffset);
            
                app.setTheta(1, th_deg(1), false);
                app.setTheta(2, th_deg(2), false);
                app.setTheta(3, th_deg(3), false);
                app.setTheta(4, th_deg(4), false);
            
                fprintf("Chosen phi = %.1f deg, cost=%.3f\n", rad2deg(bestPhi), bestCost);
            end

            function GotoPoint_smooth(app, pos_x, pos_y, pos_z)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = 0.077;
                L2 = 0.130;
                L3 = 0.124;
                L4 = 0.126;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                % ===== phi candidate list (tool pitch in r-z plane) =====
                % prefer -90deg, then scan a range
                phi0 = -pi/2;
            
                % 根据你第4关节限位大概给一个可用范围（你可调）
                % 例如允许从 -120° 到 -30° （越往后越“抬起来”）
                phi_scan = linspace(deg2rad(-120), deg2rad(-30), 60);
            
                % 让 -90° 一定排第一，并去重
                phi_list = unique([phi0, phi_scan], 'stable');
            
                % ===== best solution record =====
                bestCost = inf;
                bestTh   = [];
                bestPhi  = NaN;
            
                % ===== sweep all phi =====
                foundVertical = false;
                for phi = phi_list
                    if(foundVertical)
                        break;
                    end
            
                    % --- tool tip -> wrist point (your current method, kept) ---
                    wx = px - L4*cos(phi)*cos(theta1);
                    wy = py - L4*cos(phi)*sin(theta1);
                    wz = pz - L4*sin(phi);
            
                    r = hypot(wx, wy);
                    z = wz - L1;
            
                    % --- 2R reachability ---
                    D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);
            
                    if abs(D) > 1
                        continue; % this phi makes wrist unreachable
                    end
            
                    % numerical clamp (still good near boundary)
                    D = max(min(D,1),-1);
            
                    % --- try both elbow branches ---
                    for sgn = [1, -1]
                        theta3 = atan2(sgn*sqrt(1-D^2), D);
                        theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                        theta4 = phi - theta2 - theta3;
            
                        % ===== joint limits check (same as your setTheta clamp) =====
                        % Convert to degrees in UI convention (before offset)
                        th = [theta1 theta2 theta3 theta4];
                        th_deg = rad2deg(th - app.thetaOffset);
            
                        % idx1..3: [-90,90], idx4: [-119,90]
                        if th_deg(1) < -90 || th_deg(1) > 90,   continue; end
                        if th_deg(2) < -90 || th_deg(2) > 90,   continue; end
                        if th_deg(3) < -90 || th_deg(3) > 90,   continue; end
                        if th_deg(4) < -119 || th_deg(4) > 90,  continue; end
            
                        % ===== cost function: prefer phi near -90°, and avoid huge wrist angle =====
                        % 你可以调权重：wPhi 越大越“死命保持竖直”
                        wPhi = 100;
                        wWrist = 1;
                        cost = wPhi*abs(phi - phi0) + wWrist*abs(theta4);
            
                        if cost < bestCost
                            bestCost = cost;
                            bestTh   = th;
                            bestPhi  = phi;
                            if(phi == -pi/2)
                                foundVertical = true;
                                disp("Found Vertical");
                                break; 
                            end
                        end
                    end
                end
            
                % ===== apply best solution =====
                if isempty(bestTh)
                    disp("IK failed: no feasible solution in phi range + joint limits.");
                    return;
                end

                th_deg = rad2deg(bestTh - app.thetaOffset);
                fprintf("Chosen phi = %.1f deg, cost=%.3f\n", rad2deg(bestPhi), bestCost);

                app.setTheta_sim(1, th_deg(1), false);
                app.setTheta_sim(2, th_deg(2), false);
                app.setTheta_sim(3, th_deg(3), false);
                app.setTheta_sim(4, th_deg(4), false);
            
                if(app.isConnected)
                    disp(th_deg);
                    app.set_omega(app, th_deg);
                end
            
            end


            function theta_ui = motorRad2uiRad(app, theta_motor)
                % motor = sign*ui - offset
                % => ui = (motor + offset) ./ sign
                theta_ui = (theta_motor) ./ app.thetaSign(1:4);
            end

            function p_wrist = readRealWristXYZ(app)
                % 读4个关节当前位置（Dynamixel position）
                ids = app.DXL_IDS(1:4);
            
                pos = zeros(1,4);
                for k = 1:4
                    pos(k) = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION);
                end
            
                % position -> theta_motor(rad)
                theta_motor = app.pos2rad(pos);     % 1x4 rad（电机角，含实际方向）
            
                % motor角 -> UI角（去掉 sign/offset，保证跟 fk_T_all 输入一致）
                theta_ui = app.motorRad2uiRad(theta_motor);
            
                % 用FK算点
                P = app.fk_points(theta_ui);   % 3x5
            
                % 腕点 = 第3节末端 = P(:,4)
                p_wrist = P(:,4).';            % 1x3
            end

            function theta1 = readTheta1(app)
                if(app.isConnected)
                    theta1 = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 11, app.ADDR_PRO_PRESENT_POSITION);
                else
                    theta1 = 0;
                end
            end

            function gripper_hold(app)
                setTheta(app, 5, app.GRIP_HOLD_POS);
                pause(0.2);
            end

            function gripper_release(app)
                setTheta(app, 5, app.GRIP_RELEASE_POS);
                pause(0.2);
            end

            function real_pos = grid2pos(~, grid_pos)
                real_pos = grid_pos/7.5*0.2;
            end

            function grid_pos = pos2grid(~, real_pos)
                grid_pos = real_pos/0.2*7.5;
            end

            function set_position_mode(app)
                if(app.isConnected)
                    for id = app.DXL_IDS
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 3);
                    end
                    disp("Switched to Position Control Mode");
                else
                    disp("Not Connected")
                end
            end

            function set_velocity_mode(app)
                if(app.isConnected)
                    for id = app.DXL_IDS
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 1);
                    end
                    disp("Switched to Velocity Control Mode");
                else
                    disp("Not Connected")
                end
            end
                

            function GotoPoint_Angle(app, pos_x, pos_y, pos_z, w_angle)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = 0.077;
                L2 = 0.130;
                L3 = 0.124;
                L4 = 0.126;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                phi = w_angle;
                wx = px - L4*cos(phi)*cos(theta1);
                wy = py - L4*cos(phi)*sin(theta1);
                wz = pz - L4*sin(phi);

                r = hypot(wx, wy);
                z = wz - L1;
        
                % --- 2R reachability ---
                D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);

                if abs(D) > 1
                    disp("Can't Reach Position"); % this phi makes wrist unreachable
                    return;
                end

                D = max(min(D,1),-1);

                theta3_candidates = [atan2(sqrt(1-D^2), D),-atan2(sqrt(1-D^2), D)];
                theta3 = min(theta3_candidates);
                theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                theta4 = phi - theta2 - theta3;
        
                % ===== joint limits check (same as your setTheta clamp) =====
                % Convert to degrees in UI convention (before offset)
                th = [theta1 theta2 theta3 theta4];
                th_deg = rad2deg(th - app.thetaOffset);
    
                % idx1..3: [-90,90], idx4: [-119,90]
                if th_deg(1) < -90 || th_deg(1) > 90,   disp("Value out of limit"); return; end
                if th_deg(2) < -90 || th_deg(2) > 90,   disp("Value out of limit"); return; end
                if th_deg(3) < -90 || th_deg(3) > 90,   disp("Value out of limit"); return; end
                if th_deg(4) < -119 || th_deg(4) > 90,  disp("Value out of limit"); return; end
            
                app.setTheta(1, th_deg(1), false);
                app.setTheta(2, th_deg(2), false);
                app.setTheta(3, th_deg(3), false);
                app.setTheta(4, th_deg(4), false);
            end

            function pick_block(app, pos_x, pos_y, pos_z)

                disp("Going to Point with Z")
                disp(pos_z)
                unify_pos = 0.08;
                wait_pos_z = pos_z+0.08;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                gripper_release(app);
                GotoPoint(app, pos_x, pos_y, pos_z);
                pause(0.3);
                gripper_hold(app);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Pick Block Endded");
            end

            function put_block(app, pos_x, pos_y, pos_z)
                unify_pos = 0.08;
                wait_pos_z = 0.1;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, pos_z);
                pause(0.3);
                gripper_release(app);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Put Block Endded");
            end
    
            function pick_block_angle(app, pos_x, pos_y, pos_z)
                unify_pos = 0.08;
                wait_pos_z = 0.1;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint_Angle(app, pos_x, pos_y, wait_pos_z, 0);
                pause(0.3);
                gripper_release(app);
                pause(0.3);
                GotoPoint_Angle(app, pos_x, pos_y, pos_z, 0);
                pause(0.3);
                gripper_hold(app);
                pause(0.3);
                GotoPoint_Angle(app, pos_x, pos_y, wait_pos_z, 0);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Pick Block with Angle Endded");
            end

            function syncWriteProfileVelocity(app, ids, vel_units)
            % ids: [1xN] 电机ID
            % vel_units: [1xN] 写入寄存器的速度单位(int32)，例如你 degps2velTick 的输出
            %
            % 注意：Profile Velocity 地址通常是 112，长度 4 字节（XM430 / Protocol 2.0）
            
                ADDR_PROFILE_VELOCITY = app.ADDR_PRO_PROFILE_VELOCITY; % 你已有
                LEN_PROFILE_VELOCITY  = 4;
            
                % 1) 创建 GroupSyncWrite 句柄
                group_num = groupSyncWrite(app.port_num, app.PROTOCOL_VERSION, ...
                                           ADDR_PROFILE_VELOCITY, LEN_PROFILE_VELOCITY);
            
                % 2) 逐个添加参数
                for i = 1:numel(ids)
                    id = ids(i);
            
                    % 打包成 4 字节 little-endian
                    param = typecast(int32(vel_units(i)), 'uint8');
            
                    % addParam：把 (id, param[4]) 加入同步写缓冲
                    dxl_addparam_result = groupSyncWriteAddParam(group_num, id, param, LEN_PROFILE_VELOCITY);
            
                    if dxl_addparam_result ~= 1
                        error("groupSyncWriteAddParam failed. ID=%d", id);
                    end
                end
            
                % 3) 一次性发包
                dxl_comm_result = groupSyncWriteTxPacket(group_num);
            
                if dxl_comm_result ~= 0
                    % 0 通常表示 COMM_SUCCESS（具体常量你工程里可能有定义）
                    warning("groupSyncWriteTxPacket returned %d", dxl_comm_result);
                end
            
                % 4) 清空参数（下一轮再加）
                groupSyncWriteClearParam(group_num);
            end


            function vel_tick = degps2velTick(~, vel_degps)
                    RPM_PER_UNIT = 0.229;
                    DEG_PER_RPM  = 6.0;
                    MAX_UNIT = 1023;
                    vel_rpm = vel_degps / DEG_PER_RPM;
                    vel_unit = vel_rpm / RPM_PER_UNIT;
                    vel_tick = int32(round(vel_unit));
                    vel_tick = min(max(vel_tick, -MAX_UNIT), MAX_UNIT);
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                disp("Started Running");
                % 3D 视图初始化
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                view(app.UIAxes, 3);
                xlabel(app.UIAxes,'X'); ylabel(app.UIAxes,'Y'); zlabel(app.UIAxes,'Z');
            
                % 合理的显示范围（单位 m）
                R = 0.130+0.124+0.126+0.10;
                xlim(app.UIAxes, [-R R]);
                ylim(app.UIAxes, [-R R]);
                zlim(app.UIAxes, [-R R]);


                app.UIAxes.NextPlot = 'add';  % 允许更新不重置
            
                % 先创建一个空的线对象
                app.hArmLine = plot3(app.UIAxes, nan, nan, nan, '-o', 'LineWidth', 2);
                app.hGripperTrace = plot3(app.UIAxes, nan, nan, nan, '-', 'LineWidth', 2, 'Color', 'r');
                app.gripperTraceData = [];
                app.hRealTrace = plot3(app.UIAxes, nan, nan, nan, '-', 'LineWidth', 2, 'Color', 'g');   % 绿色=真实轨迹
                app.realTraceData = [];


                % 初始角度
                app.Slider.Value = 0; app.EditField.Value = 0;
                app.Slider2.Value = 0; app.EditField2.Value = 0;
                app.Slider2.Value = 0; app.EditField3.Value = 0;
                app.Slider4.Value = 0; app.EditField4.Value = 0;
                app.Slider5.Value = 0; app.EditField5.Value = 0;
                app.X_GridEditField.Value = 4; app.Y_GridEditField.Value = 4; app.PointZEditField.Value = 0.1;
                
                app.AngleWEditField.Value = -90;
                app.X_PickEditField.Value = 6;
                app.Y_PickEditField.Value = 6;
                app.Z_PickEditField.Value = 0.03;

                app.X_PutEditField.Value = 6;
                app.Y_PutEditField.Value = -6;
                app.Z_PutEditField.Value = 0.03;

                app.DXL_IDS = [app.DXL_ID1 app.DXL_ID2 app.DXL_ID3 app.DXL_ID4 app.DXL_GRIP];

                app.updateTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period',0.05, ...          % 0.05s = 20Hz
                'TimerFcn',@(~,~)app.onTimerTick());
        
                app.redraw();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disconnectDxl(app);
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ThetaControlChanged(1, app.Slider.Value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.ThetaSliderChanging(event, 1);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.ThetaControlChanged(1, app.EditField.Value);
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.ThetaControlChanged(2, event.Value);
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            app.ThetaSliderChanging(event, 2);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            app.ThetaControlChanged(2, event.Value);
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            app.ThetaControlChanged(3, event.Value);
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            app.ThetaSliderChanging(event, 3);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.ThetaControlChanged(3, event.Value);
        end

        % Value changed function: Slider4
        function Slider4ValueChanged(app, event)
            app.ThetaControlChanged(4, event.Value);
        end

        % Value changing function: Slider4
        function Slider4ValueChanging(app, event)
            app.ThetaSliderChanging(event, 4);
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            app.ThetaControlChanged(4, event.Value);
        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
            app.ThetaControlChanged(5, event.Value);
        end

        % Value changing function: Slider5
        function Slider5ValueChanging(app, event)
            app.ThetaSliderChanging(event, 5);
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
            app.ThetaControlChanged(5, event.Value);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            connectDxl(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disconnectDxl(app);
        end

        % Callback function
        function EnableButtonPushed(app, event)
            if app.isConnected
                start(app.updateTimer);
            end
        end

        % Callback function
        function StopButtonPushed(app, event)
            if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                stop(app.updateTimer);
            end
        end

        % Button pushed function: GotoPointButton
        function GotoPointButtonPushed(app, event)
            GotoPoint(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            drawSquare(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.gripperTraceData = [];

            % 清空红色轨迹线（但不影响视角/坐标轴）
            if ~isempty(app.hGripperTrace) && isvalid(app.hGripperTrace)
                set(app.hGripperTrace, 'XData', nan, 'YData', nan, 'ZData', nan);
                app.realTraceData = [];
                set(app.hRealTrace, 'XData', nan, 'YData', nan, 'ZData', nan);
            end
            
        end

        % Button pushed function: GripperHoldButton
        function GripperHoldButtonPushed(app, event)
            gripper_hold(app);
        end

        % Button pushed function: GripperReleaseButton
        function GripperReleaseButtonPushed(app, event)
            gripper_release(app);
        end

        % Button pushed function: GotowithAngleButton
        function GotowithAngleButtonPushed(app, event)
            GotoPoint_Angle(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value, deg2rad(app.AngleWEditField.Value));
        end

        % Button pushed function: PickButton
        function PickButtonPushed(app, event)
            pick_block(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value), app.Z_PickEditField.Value);
        end

        % Button pushed function: PutButton
        function PutButtonPushed(app, event)
            put_block(app, grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value);
        end

        % Button pushed function: PickandPutButton
        function PickandPutButtonPushed(app, event)
            pick_block(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value),app.Z_PickEditField.Value);
            pause(0.3);
            put_block(app, grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value);
        end

        % Button pushed function: PickwithAngleButton
        function PickwithAngleButtonPushed(app, event)
            pick_block_angle(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value), app.Z_PickEditField.Value);
        end

        % Button pushed function: GotoPointSmoothButton
        function GotoPointSmoothButtonPushed(app, event)
            GotoPoint_smooth(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value);
        end

        % Button pushed function: PositionModeButton
        function PositionModeButtonPushed(app, event)
            set_position_mode(app)
        end

        % Button pushed function: VelocityModeButton
        function VelocityModeButtonPushed(app, event)
            set_velocity_mode(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-90 90];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-90 90];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-119 90];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged, true);
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-90 90];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.ValueChangingFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GotoPointButton = uibutton(app.UIFigure, 'push');
            app.GotoPointButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPointButtonPushed, true);
            app.GotoPointButton.Text = 'Go to Point';
            app.X_GridEditFieldLabel = uilabel(app.UIFigure);
            app.X_GridEditFieldLabel.Text = 'X_Grid';
            app.X_GridEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_GridEditFieldLabel = uilabel(app.UIFigure);
            app.Y_GridEditFieldLabel.Text = 'Y_Grid';
            app.Y_GridEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointZEditFieldLabel = uilabel(app.UIFigure);
            app.PointZEditFieldLabel.Text = 'PointZ';
            app.PointZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.GripperHoldButton = uibutton(app.UIFigure, 'push');
            app.GripperHoldButton.ButtonPushedFcn = createCallbackFcn(app, @GripperHoldButtonPushed, true);
            app.GripperHoldButton.Text = 'GripperHold';
            app.GripperReleaseButton = uibutton(app.UIFigure, 'push');
            app.GripperReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @GripperReleaseButtonPushed, true);
            app.GripperReleaseButton.Text = 'GripperRelease';
            app.AngleWEditFieldLabel = uilabel(app.UIFigure);
            app.AngleWEditFieldLabel.Text = 'AngleW';
            app.AngleWEditField = uieditfield(app.UIFigure, 'numeric');
            app.GotowithAngleButton = uibutton(app.UIFigure, 'push');
            app.GotowithAngleButton.ButtonPushedFcn = createCallbackFcn(app, @GotowithAngleButtonPushed, true);
            app.GotowithAngleButton.Text = 'Go to with Angle';
            app.X_PickEditFieldLabel = uilabel(app.UIFigure);
            app.X_PickEditFieldLabel.Text = 'X_Pick';
            app.X_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_PickEditFieldLabel = uilabel(app.UIFigure);
            app.Y_PickEditFieldLabel.Text = 'Y_Pick';
            app.Y_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_PickEditFieldLabel = uilabel(app.UIFigure);
            app.Z_PickEditFieldLabel.Text = 'Z_Pick';
            app.Z_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_PutEditFieldLabel = uilabel(app.UIFigure);
            app.X_PutEditFieldLabel.Text = 'X_Put';
            app.X_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_PutEditFieldLabel = uilabel(app.UIFigure);
            app.Y_PutEditFieldLabel.Text = 'Y_Put';
            app.Y_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_PutEditFieldLabel = uilabel(app.UIFigure);
            app.Z_PutEditFieldLabel.Text = 'Z_Put';
            app.Z_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.PickButton = uibutton(app.UIFigure, 'push');
            app.PickButton.ButtonPushedFcn = createCallbackFcn(app, @PickButtonPushed, true);
            app.PickButton.Text = 'Pick';
            app.PutButton = uibutton(app.UIFigure, 'push');
            app.PutButton.ButtonPushedFcn = createCallbackFcn(app, @PutButtonPushed, true);
            app.PutButton.Text = 'Put';
            app.PickandPutButton = uibutton(app.UIFigure, 'push');
            app.PickandPutButton.ButtonPushedFcn = createCallbackFcn(app, @PickandPutButtonPushed, true);
            app.PickandPutButton.Text = 'Pick and Put';
            app.PickwithAngleButton = uibutton(app.UIFigure, 'push');
            app.PickwithAngleButton.ButtonPushedFcn = createCallbackFcn(app, @PickwithAngleButtonPushed, true);
            app.PickwithAngleButton.Text = 'Pick with Angle';
            app.GotoPointSmoothButton = uibutton(app.UIFigure, 'push');
            app.GotoPointSmoothButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPointSmoothButtonPushed, true);
            app.GotoPointSmoothButton.Text = 'Go to Point (Smooth)';
            app.PositionModeButton = uibutton(app.UIFigure, 'push');
            app.PositionModeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionModeButtonPushed, true);
            app.PositionModeButton.Text = 'Position Mode';
            app.VelocityModeButton = uibutton(app.UIFigure, 'push');
            app.VelocityModeButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityModeButtonPushed, true);
            app.VelocityModeButton.Text = 'Velocity Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end